classdef MainWindowNew < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MainUIFigure                    matlab.ui.Figure
        ExtrasPanel                     matlab.ui.container.Panel
        BZaberStages                    matlab.ui.control.StateButton
        ZaberstagesLabel                matlab.ui.control.Label
        FunctiongeneratorLabel          matlab.ui.control.Label
        BFuncGen                        matlab.ui.control.StateButton
        SSyncVent                       matlab.ui.control.CheckBox
        AcquisitionPanel                matlab.ui.container.Panel
        SScanbox                        matlab.ui.control.CheckBox
        BConnectScanbox                 matlab.ui.control.StateButton
        SIntan                          matlab.ui.control.CheckBox
        BConnectIntan                   matlab.ui.control.StateButton
        EIntanRoot                      matlab.ui.control.EditField
        SSpikeglx                       matlab.ui.control.CheckBox
        BConnectSpikeglx                matlab.ui.control.StateButton
        SCamera                         matlab.ui.control.CheckBox
        ECameraRoot                     matlab.ui.control.EditField
        DatarootEditField_2Label        matlab.ui.control.Label
        DatarootEditFieldLabel          matlab.ui.control.Label
        BConnectCamera                  matlab.ui.control.StateButton
        ScreenPanel                     matlab.ui.container.Panel
        ERefreshRate                    matlab.ui.control.NumericEditField
        RefreshrateHzLabel              matlab.ui.control.Label
        BReconnectStim                  matlab.ui.control.Button
        BCloseStim                      matlab.ui.control.Button
        BGetRate                        matlab.ui.control.Button
        EScreenDist                     matlab.ui.control.NumericEditField
        ScreendistancecmEditFieldLabel  matlab.ui.control.Label
        DMonitor                        matlab.ui.control.DropDown
        MonitorDropDownLabel            matlab.ui.control.Label
        BRun                            matlab.ui.control.Button
        ModeButtonGroup                 matlab.ui.container.ButtonGroup
        BStimDAQ                        matlab.ui.control.RadioButton
        BStimOnly                       matlab.ui.control.RadioButton
        TProgress                       matlab.ui.control.EditField
        ProgressEditFieldLabel          matlab.ui.control.Label
        DataPanel                       matlab.ui.container.Panel
        BCheckName                      matlab.ui.control.Button
        EAnaRoots                       matlab.ui.control.EditField
        AnalyzerrootsEditFieldLabel     matlab.ui.control.Label
        ExptLabel                       matlab.ui.control.Label
        UnitLabel                       matlab.ui.control.Label
        BExpt                           matlab.ui.control.Button
        BUnit                           matlab.ui.control.Button
        EAnimal                         matlab.ui.control.EditField
        AnimalEditFieldLabel            matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            global Mstate setupDefault
            
            Mstate.running = 0;
            
            %Set GUI to the default established in configureMstate
            app.EScreenDist.Value=Mstate.screenDist;
            app.EAnaRoots.Value=Mstate.analyzerRoot;
            app.EAnimal.Value=Mstate.anim;
            app.BUnit.Text=Mstate.unit;
            app.BExpt.Text=Mstate.expt;
            %set(handles.dataRoots,'string',Mstate.dataRoot)
            
            %add monitors
            monitorName{1}=setupDefault.defaultMonitor;
            mTmp=strtrim(strsplit(setupDefault.alternativeMonitor,';'));
            for i=1:length(mTmp)
                monitorName{i+1}=mTmp{i};
            end
            app.DMonitor.Items=monitorName;
            app.DMonitor.Value=setupDefault.defaultMonitor;
            
            
            %configure setup specific parts
            if setupDefault.useMCDaq==0 || setupDefault.useVentilator==0
                app.SSyncVent.Enable=0;
            end
            
            if setupDefault.useFuncGen
                app.BFuncGen.Enable=1;
                Mstate.FuncGen=0;
            end
            
            if setupDefault.useZaber
                app.BZaberStages.Enable=1;
                Mstate.Zaber=0;
            end
            
            %acquisition specific parts
            Mstate.acqConnect=zeros(4,1);
            Mstate.acqIdx2P=1;
            Mstate.acqIdxEP=2; %intan
            Mstate.acqIdxNP=3; %neuropixels
            Mstate.acqIdxIsi=4;
            
            if ~isempty(strfind(setupDefault.setupID,'2P'))
                app.SScanbox.Enable=1;
                app.BConnectScanbox.Enable=1;
            end
            
            if ~isempty(strfind(setupDefault.setupID,'EP'))
                app.SIntan.Enable=1;
                app.BConnectIntan.Enable=1;
                app.EIntanRoot.Enable=1;
                app.EIntanRoot.Value=setupDefault.acqDataRoot;
            end

            if ~isempty(strfind(setupDefault.setupID,'NP'))
                app.SSpikeglx.Enable=1;
                app.BConnectSpikeglx.Enable=1;
            end
            
            if ~isempty(strfind(setupDefault.setupID,'ISI'))
                app.SCamera.Enable=1;
                app.BConnectCamera.Enable=1;
                app.ECameraRoot.Enable=1;
            end
            
        end

        % Value changed function: EAnimal
        function EAnimalValueChanged(app, event)
            global Mstate setupDefault AppHdl
            
            %disp('a')
            
            %set animal info
            Mstate.anim = app.EAnimal.Value;
            
            %update unit and experiment
            roots = strsplit(Mstate.analyzerRoot,';');
            dirinfo = dir(fullfile(roots{1},Mstate.anim)); %Use the first root path for the logic below
            
            if length(dirinfo) > 2 %If the animal folder exists and there are files in it
                lastunit = dirinfo(end).name(8:10);
                lastexpt = dirinfo(end).name(12:14);
                
                newunit = lastunit;
                newexpt = sprintf('%03d',str2num(lastexpt)+1); %Go to next experiment number
            else  %if animal folder does not exist or there aren't any files.  The new folder will
                %be created when you hit the 'run' button
                newunit = '000';
                newexpt = '000';
            end
            Mstate.unit = newunit;
            Mstate.expt = newexpt;
            
            app.BUnit.Text=newunit;
            app.BExpt.Text=newexpt;
            app.TProgress.Value='';
            
            if app.BStimDAQ.Value==1
                updateAcqName   %Send expt info to acquisition
            end
            
            %update GA window
            AppHdl.ga.AnimalField.Value=Mstate.anim;

            %save(setupDefault.MstateHistoryFile,'Mstate');
        end

        % Button pushed function: BUnit
        function BUnitButtonPushed(app, event)
            global Mstate setupDefault AppHdl
            
            %update unit
            newunit = sprintf('%03d',str2num(Mstate.unit)+1);
            Mstate.unit = newunit;
            app.BUnit.Text=newunit;
            
            %update expt
            newexpt = '000';
            Mstate.expt = newexpt;
            app.BExpt.Text=newexpt;
            
            %update status
            app.TProgress.Value='';
            
            if app.BStimDAQ.Value==1
                updateAcqName   %Send expt info to acquisition
            end

            %update GA window
            AppHdl.ga.UnitField.Value=Mstate.unit;
            
            %save(setupDefault.MstateHistoryFile,'Mstate');
        end

        % Button pushed function: BExpt
        function BExptButtonPushed(app, event)
            global Mstate setupDefault AppHdl
            
            %update experiment
            newexpt = sprintf('%03d',str2num(Mstate.expt)+1);
            Mstate.expt = newexpt;
            app.BExpt.Text=newexpt;
            app.TProgress.Value='';
            
            if app.BStimDAQ.Value==1
                updateAcqName   %Send expt info to acquisition
            end
            
            %update GA window
            AppHdl.ga.ExperimentField.Value=Mstate.expt;

            %save(setupDefault.MstateHistoryFile,'Mstate');
        end

        % Button pushed function: BCheckName
        function BCheckNameButtonPushed(app, event)
            global Mstate setupDefault
            
            roots = strsplit(Mstate.analyzerRoot,';');
            dirinfo = dir(fullfile(roots{1},Mstate.anim)); %Use the first root path for the logic below
            
            if length(dirinfo) > 2 %If the animal folder exists and there are files in it
                lastunit = dirinfo(end).name(8:10);
                lastexpt = dirinfo(end).name(12:14);
                
                newunit = lastunit;
                newexpt = sprintf('%03d',str2num(lastexpt)+1); %Go to next experiment number
            else  %if animal folder does not exist or there aren't any files.  The new folder will
                %be created when you hit the 'run' button
                newunit = '000';
                newexpt = '000';
            end
            Mstate.unit = newunit;
            Mstate.expt = newexpt;
            
            app.BUnit.Text=newunit;
            app.BExpt.Text=newexpt;
            app.TProgress.Value='';
            
            if app.BStimDAQ.Value==1
                updateAcqName   %Send expt info to acquisition
            end
            
            %save(setupDefault.MstateHistoryFile,'Mstate');
            
        end

        % Value changed function: DMonitor
        function DMonitorValueChanged(app, event)
            global Mstate setupDefault
            
            Mstate.monitorName = app.DMonitor.Value;
            
            updateMonitorValues
            sendMonitor
            waitforDisplayResp;
            
            %update refresh rate
            queryRefresh %response is in displaycb
            pause(.5);
            %waitforDisplayResp;
            app.ERefreshRate.Value=Mstate.refreshRate;
            
            %save(setupDefault.MstateHistoryFile,'Mstate');
        end

        % Button pushed function: BGetRate
        function BGetRateButtonPushed(app, event)
            global Mstate
            
            %update refresh rate
            queryRefresh %response is in displaycb
            pause(.5);
            %waitforDisplayResp;
            app.ERefreshRate.Value=Mstate.refreshRate;
        end

        % Value changed function: EScreenDist
        function EScreenDistValueChanged(app, event)
            %This is not actually necessary since updateMstate is always called prior
            %to showing stimuli...
            global Mstate setupDefault
            
            Mstate.screenDist = app.EScreenDist.Value;
            %save(setupDefault.MstateHistoryFile,'Mstate');
        end

        % Button pushed function: BCloseStim
        function BCloseStimButtonPushed(app, event)
            global DcomState
            
            fwrite(DcomState.serialPortHandle,'C;~')
        end

        % Button pushed function: BReconnectStim
        function BReconnectStimButtonPushed(app, event)
            configDisplayCom_tcp;
        end

        % Value changed function: BConnectScanbox
        function BConnectScanboxValueChanged(app, event)
            global Mstate;
            
            if app.BConnectScanbox.Value==1
                %connect to scanbox
                open_sbserver;
                Mstate.acqConnect(Mstate.acqIdx2P)=1;
                
                %update button label
                app.BConnectScanbox.Text='Disconnect';
            else
                %disconnect from scanbox
                close_sbserver;
                Mstate.acqConnect(Mstate.acqIdx2P)=0;
                
                %update button label
                app.BConnectScanbox.Text='Connect';
            end
            
        end

        % Value changed function: BConnectIntan
        function BConnectIntanValueChanged(app, event)
            global Mstate;
            
            if app.BConnectIntan.Value==1
                %connect to intan
                configIntanCom;
                Mstate.acqConnect(Mstate.acqIdxEP)=1;
                
                %update button label
                app.BConnectIntan.Text='Disconnect';
            else
                %disconnect from intan
                %close_sbserver;
                Mstate.acqConnect(Mstate.acqIdxEP)=0;
                
                %update button label
                app.BConnectIntan.Text='Connect';
            end
            
        end

        % Value changed function: BConnectSpikeglx
        function BConnectSpikeglxValueChanged(app, event)
            global Mstate;
            
            if app.BConnectSpikeglx.Value==1
                %connect to spikeglx
                openSpikeglxCom;
                Mstate.acqConnect(Mstate.acqIdxNP)=1;
                
                %update button label
                app.BConnectSpikeglx.Text='Disconnect';
            else
                %disconnect from spikeglx
                closeSpikeglxCom;
                Mstate.acqConnect(Mstate.acqIdxNP)=0;
                
                %update button label
                app.BConnectSpikeglx.Text='Connect';
            end
            
        end

        % Value changed function: BConnectCamera
        function BConnectCameraValueChanged(app, event)
            global Mstate;
            
            if app.BConnectCamera.Value==1
                %connect to camera
                %configIntanCom;
                Mstate.acqConnect(Mstate.acqIdxIsi)=1;
                
                %update button label
                app.BConnectCamera.Text='Disconnect';
            else
                %disconnect from scanbox
                %close_sbserver;
                Mstate.acqConnect(Mstate.acqIdxIsi)=0;
                
                %update button label
                app.BConnectCamera.Text='Connect';
            end
        end

        % Value changed function: EIntanRoot
        function EIntanRootValueChanged(app, event)
            global Mstate;
            
            Mstate.dataRoot=app.EIntanRoot.Value;
            if app.BStimDAQ.Value==1
                updateAcqName   %Send expt info to acquisition
            end
        end

        % Value changed function: ECameraRoot
        function ECameraRootValueChanged(app, event)
            global Mstate;
            
            Mstate.IsiDataRoot=app.ECameraRoot.Value;
            if app.BStimDAQ.Value==1
                updateAcqName   %Send expt info to acquisition
            end
        end

        % Value changed function: BFuncGen
        function BFuncGenValueChanged(app, event)
            global Mstate;
            
            if app.BFuncGen.Value==1
                %connect func gen
                connectFuncGen(1);
                Mstate.FuncGen=1;
                
                %update button label
                app.BFuncGen.Text='Disconnect';
            else
                %disconnect func gen
                connectFuncGen(0);
                Mstate.FuncGen=0;
                
                %update button label
                app.BFuncGen.Text='Connect';
            end
        end

        % Value changed function: BZaberStages
        function BZaberStagesValueChanged(app, event)
            global Mstate;
            
            if app.BZaberStages.Value==1
                %connect func gen
                connectZaber(1);
                Mstate.Zaber=1;
                
                %update button label
                app.BZaberStages.Text='Disconnect';
            else
                %disconnect func gen
                connectZaber(0);
                Mstate.Zaber=0;
                
                %update button label
                app.BZaberStages.Text='Connect';
            end
        end

        % Button pushed function: BRun
        function BRunPushed(app, event)
            %main experiment function
            global Mstate AppHdl trialno Lstate setupDefault
            
            %if ~exist(setupDefault.ExperimentMasterFile,'file')
            %    resetExperimentMasterListFile;
            %end
            %tempStruct = load(setupDefault.ExperimentMasterFile); %generates expts and count
            %count = tempStruct.count;
            %expts = tempStruct.expts;
            
            %Run it!
            if ~Mstate.running
                
                %Check if this analyzer file already exists!
                roots = strtrim(strsplit(Mstate.analyzerRoot,';'));
                for i = 1:length(roots)  %loop through each root
                    title = [Mstate.anim '_' sprintf('u%s',Mstate.unit) '_' Mstate.expt];
                    dd = fullfile(roots{i},Mstate.anim,[title '.analyzer']);
                    
                    if(exist(dd))
                        uialert(app.MainUIFigure,'File exists!!!  Please advance experiment before running','Error');
                        return
                    end
                end
                
                %check whether breathing is possible
                if app.SSyncVent.Value==1
                    if checkSyncV==0
                        uialert(app.MainUIFigure,'Trial too long to sync ventilator to stim!','Error');
                        return
                    end
                end
                
                %check whether daq has been connected if necessary
                if app.BStimDAQ.Value==1
                    if sum(Mstate.acqConnect)==0
                        uialert(app.MainUIFigure,'Acquistion not connected! Please fix before running.','Error');
                        return
                    end
                end
                
                %update refresh rate
                queryRefresh %response is in displaycb
                waitforDisplayResp;
                app.ERefreshRate.Value=Mstate.refreshRate;
                
                
                Mstate.running = 1;  %Global flag for interrupt in real-time loop ('Abort')
                
                %Update states
                updateMstate
                updatePstate 
                updateLstate
                
                
                %update randum number generator seed
                rng(datenum(date)+100*str2double(Mstate.unit)+str2double(Mstate.expt));
                
                makeLoop;  %makes 'looperInfo'.  This must be done before saving the analyzer file.
                
                saveExptParams  %Save .analyzer. Do this before running... in case something crashes
                
                %count = count + 1; %Save for database
                %expts(count).Mstate = Mstate;
                %expts(count).Lstate = Lstate;
                %expts(count).abort = 0;
                
                %save(setupDefault.ExperimentMasterFile,'expts','count','-append');
                
                app.BRun.Text='Abort';
                
                %Send animal info
                sendMinfo
                disp('send M')
                waitforDisplayResp;
               
                %Send initial parameters to display
                modID = getmoduleID;
                if setupDefault.TCPforParams==1 %use tcp/ip and send entire string
                    sendPinfo(modID)
                else
                    sendPfile(modID)
                end
                disp('send P')
                waitforDisplayResp;
                disp('done P')
                
                
                %Get the Acquisition ready:
                if app.BStimDAQ.Value==1
                    pause(5);
                    startAcq;
                    pause(2);
                end
                
                % clear all current trial details from Looper window
                for ii=1:5                   
                    eval(['AppHdl.looper.TTrial' num2str(ii) '.Text="";']);
                end
                
                %disable play in params window (enforce that send is pressed before hitting
                %play after experiment ends)
                AppHdl.params.BPlay.Enable=0;

                %
                trialno = 1;
                
                run2  %gets recalled after each trial (in 'endAcquisition' or 'Displaycb')
                
                
            else
                Mstate.running = 0;  %Global flag for interrupt in real-time loop ('Abort')
                app.BRun.Text='Run';
                
                %expts(count).abort = 1;
                %save(setupDefault.ExperimentMasterFile,'expts','-append');
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MainUIFigure = uifigure('Visible', 'off');
            app.MainUIFigure.Name = 'Main';
            app.DataPanel = uipanel(app.MainUIFigure);
            app.DataPanel.Title = 'Data';
            app.AnimalEditFieldLabel = uilabel(app.DataPanel);
            app.AnimalEditFieldLabel.Text = 'Animal';
            app.EAnimal = uieditfield(app.DataPanel, 'text');
            app.EAnimal.ValueChangedFcn = createCallbackFcn(app, @EAnimalValueChanged, true);
            app.BUnit = uibutton(app.DataPanel, 'push');
            app.BUnit.ButtonPushedFcn = createCallbackFcn(app, @BUnitButtonPushed, true);
            app.BUnit.Text = '000';
            app.BExpt = uibutton(app.DataPanel, 'push');
            app.BExpt.ButtonPushedFcn = createCallbackFcn(app, @BExptButtonPushed, true);
            app.BExpt.Text = '000';
            app.UnitLabel = uilabel(app.DataPanel);
            app.UnitLabel.Text = 'Unit';
            app.ExptLabel = uilabel(app.DataPanel);
            app.ExptLabel.Text = 'Expt';
            app.AnalyzerrootsEditFieldLabel = uilabel(app.DataPanel);
            app.AnalyzerrootsEditFieldLabel.Text = 'Analyzer root(s)';
            app.EAnaRoots = uieditfield(app.DataPanel, 'text');
            app.BCheckName = uibutton(app.DataPanel, 'push');
            app.BCheckName.ButtonPushedFcn = createCallbackFcn(app, @BCheckNameButtonPushed, true);
            app.BCheckName.Text = 'Get';
            app.ProgressEditFieldLabel = uilabel(app.MainUIFigure);
            app.ProgressEditFieldLabel.Text = 'Progress';
            app.TProgress = uieditfield(app.MainUIFigure, 'text');
            app.TProgress.Editable = 'off';
            app.ModeButtonGroup = uibuttongroup(app.MainUIFigure);
            app.ModeButtonGroup.Title = 'Mode';
            app.BStimOnly = uiradiobutton(app.ModeButtonGroup);
            app.BStimOnly.Text = 'Stimulus only';
            app.BStimOnly.Value = true;
            app.BStimDAQ = uiradiobutton(app.ModeButtonGroup);
            app.BStimDAQ.Text = 'Stimulus + DAQ';
            app.BRun = uibutton(app.MainUIFigure, 'push');
            app.BRun.ButtonPushedFcn = createCallbackFcn(app, @BRunPushed, true);
            app.BRun.Text = 'Run';
            app.ScreenPanel = uipanel(app.MainUIFigure);
            app.ScreenPanel.Title = 'Screen';
            app.MonitorDropDownLabel = uilabel(app.ScreenPanel);
            app.MonitorDropDownLabel.Text = 'Monitor';
            app.DMonitor = uidropdown(app.ScreenPanel);
            app.DMonitor.Items = {};
            app.DMonitor.ValueChangedFcn = createCallbackFcn(app, @DMonitorValueChanged, true);
            app.DMonitor.Value = {};
            app.ScreendistancecmEditFieldLabel = uilabel(app.ScreenPanel);
            app.ScreendistancecmEditFieldLabel.Text = 'Screen distance (cm)';
            app.EScreenDist = uieditfield(app.ScreenPanel, 'numeric');
            app.EScreenDist.ValueChangedFcn = createCallbackFcn(app, @EScreenDistValueChanged, true);
            app.BGetRate = uibutton(app.ScreenPanel, 'push');
            app.BGetRate.ButtonPushedFcn = createCallbackFcn(app, @BGetRateButtonPushed, true);
            app.BGetRate.Text = 'Get';
            app.BCloseStim = uibutton(app.ScreenPanel, 'push');
            app.BCloseStim.ButtonPushedFcn = createCallbackFcn(app, @BCloseStimButtonPushed, true);
            app.BCloseStim.Text = 'Close Display';
            app.BReconnectStim = uibutton(app.ScreenPanel, 'push');
            app.BReconnectStim.ButtonPushedFcn = createCallbackFcn(app, @BReconnectStimButtonPushed, true);
            app.BReconnectStim.Text = {'Reconnect Display'; ''};
            app.RefreshrateHzLabel = uilabel(app.ScreenPanel);
            app.RefreshrateHzLabel.Text = 'Refresh rate (Hz)';
            app.ERefreshRate = uieditfield(app.ScreenPanel, 'numeric');
            app.ERefreshRate.Editable = 'off';
            app.AcquisitionPanel = uipanel(app.MainUIFigure);
            app.AcquisitionPanel.Title = 'Acquisition';
            app.BConnectCamera = uibutton(app.AcquisitionPanel, 'state');
            app.BConnectCamera.ValueChangedFcn = createCallbackFcn(app, @BConnectCameraValueChanged, true);
            app.BConnectCamera.Enable = 'off';
            app.BConnectCamera.Text = 'Connect';
            app.DatarootEditFieldLabel = uilabel(app.AcquisitionPanel);
            app.DatarootEditFieldLabel.Enable = 'off';
            app.DatarootEditFieldLabel.Text = 'Data root';
            app.DatarootEditField_2Label = uilabel(app.AcquisitionPanel);
            app.DatarootEditField_2Label.Enable = 'off';
            app.DatarootEditField_2Label.Text = 'Data root';
            app.ECameraRoot = uieditfield(app.AcquisitionPanel, 'text');
            app.ECameraRoot.ValueChangedFcn = createCallbackFcn(app, @ECameraRootValueChanged, true);
            app.ECameraRoot.Enable = 'off';
            app.SCamera = uicheckbox(app.AcquisitionPanel);
            app.SCamera.Enable = 'off';
            app.SCamera.Text = 'Camera';
            app.BConnectSpikeglx = uibutton(app.AcquisitionPanel, 'state');
            app.BConnectSpikeglx.ValueChangedFcn = createCallbackFcn(app, @BConnectSpikeglxValueChanged, true);
            app.BConnectSpikeglx.Enable = 'off';
            app.BConnectSpikeglx.Text = 'Connect';
            app.SSpikeglx = uicheckbox(app.AcquisitionPanel);
            app.SSpikeglx.Enable = 'off';
            app.SSpikeglx.Text = 'SpikeGLX';
            app.EIntanRoot = uieditfield(app.AcquisitionPanel, 'text');
            app.EIntanRoot.ValueChangedFcn = createCallbackFcn(app, @EIntanRootValueChanged, true);
            app.EIntanRoot.Enable = 'off';
            app.BConnectIntan = uibutton(app.AcquisitionPanel, 'state');
            app.BConnectIntan.ValueChangedFcn = createCallbackFcn(app, @BConnectIntanValueChanged, true);
            app.BConnectIntan.Enable = 'off';
            app.BConnectIntan.Text = 'Connect';
            app.SIntan = uicheckbox(app.AcquisitionPanel);
            app.SIntan.Enable = 'off';
            app.SIntan.Text = 'Intan';
            app.BConnectScanbox = uibutton(app.AcquisitionPanel, 'state');
            app.BConnectScanbox.ValueChangedFcn = createCallbackFcn(app, @BConnectScanboxValueChanged, true);
            app.BConnectScanbox.Enable = 'off';
            app.BConnectScanbox.Text = 'Connect';
            app.SScanbox = uicheckbox(app.AcquisitionPanel);
            app.SScanbox.Enable = 'off';
            app.SScanbox.Text = 'Scanbox';
            app.ExtrasPanel = uipanel(app.MainUIFigure);
            app.ExtrasPanel.Title = 'Extras';
            app.SSyncVent = uicheckbox(app.ExtrasPanel);
            app.SSyncVent.Text = 'Sync Ventilator (off during predelay + stimulus)';
            app.BFuncGen = uibutton(app.ExtrasPanel, 'state');
            app.BFuncGen.ValueChangedFcn = createCallbackFcn(app, @BFuncGenValueChanged, true);
            app.BFuncGen.Enable = 'off';
            app.BFuncGen.Text = 'Connect';
            app.FunctiongeneratorLabel = uilabel(app.ExtrasPanel);
            app.FunctiongeneratorLabel.Text = 'Function generator';
            app.ZaberstagesLabel = uilabel(app.ExtrasPanel);
            app.ZaberstagesLabel.Text = 'Zaber stages';
            app.BZaberStages = uibutton(app.ExtrasPanel, 'state');
            app.BZaberStages.ValueChangedFcn = createCallbackFcn(app, @BZaberStagesValueChanged, true);
            app.BZaberStages.Enable = 'off';
            app.BZaberStages.Text = 'Connect';
            % Show the figure after all components are created
            app.MainUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MainWindowNew

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MainUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MainUIFigure)
        end
    end
end