classdef paramSelectNew < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ParamsUIFigure       matlab.ui.Figure
        BLoad                matlab.ui.control.Button
        BPlay                matlab.ui.control.Button
        BSend                matlab.ui.control.Button
        BSave                matlab.ui.control.Button
        TModDescrp           matlab.ui.control.TextArea
        TblParam             matlab.ui.control.Table
        DModule              matlab.ui.control.DropDown
        ModuleDropDownLabel  matlab.ui.control.Label
    end

    
    methods (Access = private)
        %refresh the parameters list
        function refreshParamView(app,Pstate,Pdoc)
            
            for i=1:length(Pstate.param)
                parname{i}=Pstate.param{i}{1};
                parval{i}=num2str(Pstate.param{i}{3});
                parunit{i}=Pstate.param{i}{5};
                
                if isfield(Pdoc,'param')
                    pardoc{i}=Pdoc.param{i};
                else
                    pardoc{i}='';
                end
            end
            
            t=table(parname',parval',parunit',pardoc');
            app.TblParam.Data=t;
            
            if isfield(Pdoc,'general')
                app.TModDescrp.Value=Pdoc.general;
            else
                app.TModDescrp.Value='';
            end
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            global Pstate Pdoc stereoFlag;
            
            if stereoFlag==0
                mList=moduleListMaster('P'); %get correct module list
            else
                mList=moduleListStereo('P');
            end
            
            for i=1:length(mList)
                modStrings{i} = mList{i}{2};
            end
            app.DModule.Items=modStrings;
            app.DModule.ItemsData=[1:length(mList)];
            
            refreshParamView(app,Pstate,Pdoc);
        end

        % Value changed function: DModule
        function DModuleValueChanged(app, event)
            global Pstate Pdoc;
            
            configurePstate(app.DModule.Value,'P');
            initializeModule(app.DModule.Value);
            refreshParamView(app,Pstate,Pdoc); %we're changing lists
        end

        % Button pushed function: BLoad
        function BLoadButtonPushed(app, event)
            global Pstate  PstateHistory setupDefault Pdoc
            
            [file,path] = uigetfile(...
                {'*.param;*.analyzer','All Stimulator Files (.param, .analyzer)';...
                '*.*','All Files' },...
                'Load parameter state',...
                setupDefault.loopParamRoot);
            
            if file  %if 'cancel' was not pressed
                %load file
                ffile = fullfile(path,file);
                tmp=load(ffile,'-mat');
                
                %parse according to type
                id = find(file == '.');
                fext = file(id+1:end);
                
                Pdoctmp=struct;
                if strcmp(fext,'param')  %selecting saved param file
                    Ptmp=tmp.Pstate;
                    if isfield(tmp,'Pdoc')
                        Pdoctmp=tmp.Pdoc;
                    end
                elseif strcmp(fext,'analyzer')  %selecting old experiment
                    Ptmp = tmp.Analyzer.P;
                    if isfield(tmp.Analyzer,'Pdoc')
                        Pdoctmp=tmp.Analyzer.Pdoc;
                    end
                end
                %check whether we're loading parameters for the correct module
                modId = getmoduleID; %this returns the 2 letter code for the module selected in the dropdown
                disp(modId)
                if isfield(Ptmp,'type') && strcmp(Ptmp.type,modId)==0
                    uialert(app.ParamsUIFigure,'Parameter file does not match selected module','Error');
                else
                    Pstate=Ptmp;
                    Pdoc=Pdoctmp;
                    if ~isfield(Pstate,'type')
                        Pstate.type=modId;
                    end
                    PstateHistory{app.DModule.Value} = Pstate;
                    refreshParamView(app,Pstate,Pdoc);
                    disp(['File loaded: ' file]);
                end
                
            end
        end

        % Button pushed function: BSave
        function BSaveButtonPushed(app, event)
            global Pstate Pdoc setupDefault
            
            updatePstate; %update to current values
            
            [file,path] = uiputfile(fullfile(setupDefault.loopParamRoot,'*.param'),'Save as');
            
            if file  %if 'cancel' was not pressed
                ffile = fullfile(path, file);
                save(ffile,'Pstate','Pdoc')
            end
        end

        % Button pushed function: BSend
        function BSendButtonPushed(app, event)
            global Mstate
            
            app.BPlay.Enable=0;
            
            Mstate.running = 0; %I don't think this is necessary, but doing it just in case for when I do 'sendMinfo'
            
            updateMstate %this is only necessary for screendistance
            updatePstate %this is where parameters are now updated since the switch to the table
            
            modId = getmoduleID;
            
            %Send parameters to display
            disp('Sending stimulus params.');
            sendPinfo(modId)
            waitforDisplayResp;
            sendMinfo
            waitforDisplayResp;
            
            %Tell it to buffer the stimulus
            disp('Building stimulus and buffering frames.');
            tic;
            buildStimulusSample;
            waitforDisplayResp;
            timeElapsed = toc;
            fprintf('\tBuild time: %4.2f seconds\n',timeElapsed);
            
            disp('Ready to play.');
            app.BPlay.Enable=1;
        end

        % Button pushed function: BPlay
        function BPlayButtonPushed(app, event)
            app.BSend.Enable=0;
            pause(0.1)
            modId = getmoduleID;
            startStimulus(modId)
            
            waitforDisplayResp;
            app.BSend.Enable=1;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ParamsUIFigure = uifigure('Visible', 'off');
            app.ParamsUIFigure.Name = 'Params';
            app.ModuleDropDownLabel = uilabel(app.ParamsUIFigure);
            app.ModuleDropDownLabel.Text = 'Module';
            app.DModule = uidropdown(app.ParamsUIFigure);
            app.DModule.Items = {};
            app.DModule.ValueChangedFcn = createCallbackFcn(app, @DModuleValueChanged, true);
            app.DModule.Value = {};
            app.TblParam = uitable(app.ParamsUIFigure);
            app.TblParam.ColumnName = {'Name'; 'Value'; 'Unit'; 'Docu'};
            app.TblParam.RowName = {};
            app.TblParam.ColumnEditable = [false true false false];
            app.TModDescrp = uitextarea(app.ParamsUIFigure);
            app.TModDescrp.Editable = 'off';
            app.BSave = uibutton(app.ParamsUIFigure, 'push');
            app.BSave.ButtonPushedFcn = createCallbackFcn(app, @BSaveButtonPushed, true);
            app.BSave.Text = 'Save';
            app.BSend = uibutton(app.ParamsUIFigure, 'push');
            app.BSend.ButtonPushedFcn = createCallbackFcn(app, @BSendButtonPushed, true);
            app.BSend.Text = 'Send';
            app.BPlay = uibutton(app.ParamsUIFigure, 'push');
            app.BPlay.ButtonPushedFcn = createCallbackFcn(app, @BPlayButtonPushed, true);
            app.BPlay.Enable = 'off';
            app.BPlay.Text = 'Play';
            app.BLoad = uibutton(app.ParamsUIFigure, 'push');
            app.BLoad.ButtonPushedFcn = createCallbackFcn(app, @BLoadButtonPushed, true);
            app.BLoad.Text = 'Load';
            % Show the figure after all components are created
            app.ParamsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = paramSelectNew

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ParamsUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ParamsUIFigure)
        end
    end
end